classdef SyS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DirectorioEditField        matlab.ui.control.EditField
        DirectorioEditFieldLabel   matlab.ui.control.Label
        DuracionEditField          matlab.ui.control.EditField
        DuracionEditFieldLabel     matlab.ui.control.Label
        CreadoelEditField          matlab.ui.control.EditField
        CreadoelEditFieldLabel     matlab.ui.control.Label
        TamaoKBEditField           matlab.ui.control.EditField
        TamaoKBEditFieldLabel      matlab.ui.control.Label
        NombreEditField            matlab.ui.control.EditField
        NombreEditFieldLabel       matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        Label                      matlab.ui.control.Label
        SelecioneunamuestraButton  matlab.ui.control.Button
        fileStatus                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelecioneunamuestraButton
        function SelecioneunamuestraButtonPushed(app, event)
            global file path 
            [file, path] = uigetfile('*.mat', 'Seleccione un archivo name.mat');
            
            if isequal(file, 0)
                % Crear un texto y editarlo para el usuario %
                app.fileStatus.Text = 'No se pudo abrir';
                disp('No se pudo abrir');
            else 
                %app.fileStatus.Text = fullfile(path, file);
                %disp(fullfile(path, file));
                %data = loadMatFile(fullfile(path, file));
                %disp(data);
                app.DirectorioEditField.Value = fullfile(path, file);
                
                % Llama a la función loadMatFileProperties
                fileInfo = loadMatFileProperties(fullfile(path, file));
        
                % Muestra la información del archivo en la interfaz
                infoText = '';
                
                 % Añadir duraciones de las variables si existen
                variableNames = fieldnames(fileInfo.duration);
                for i = 1:length(variableNames)
                    varName = variableNames{i};
                    durationText = sprintf('%s: %.2f s\n', varName, fileInfo.duration.(varName));
                    app.DuracionEditField.Value = strcat(infoText, durationText);
                end
                app.CreadoelEditField.Value =  fileInfo.date;
                app.NombreEditField.Value = fileInfo.name;
                app.TamaoKBEditField.Value = sprintf('%s', fileInfo.size / 1024);
                
                
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            global file path
            value = app.ListBox.Value;
            
            switch value
                case 'Entradas'
                   % Mostrar EditFields y Labels asociados
                   app.CreadoelEditField.Visible = 'on';
                   app.CreadoelEditFieldLabel.Visible = 'on';
                   app.DirectorioEditField.Visible = 'on';
                   app.DirectorioEditFieldLabel.Visible = 'on';
                   app.NombreEditField.Visible = 'on';
                   app.NombreEditFieldLabel.Visible = 'on';    
                   app.fileStatus.Visible = 'on';    
                   app.TamaoKBEditField.Visible = 'on';
                   app.TamaoKBEditFieldLabel.Visible = 'on';         
                   app.DuracionEditField.Visible = 'on';
                   app.DuracionEditFieldLabel.Visible = 'on';      
                   app.SelecioneunamuestraButton.Visible = 'on';


                case 'Visualizar'
                   app.CreadoelEditField.Visible = 'off';
                   app.CreadoelEditFieldLabel.Visible = 'off';   
                   app.DirectorioEditField.Visible = 'off';
                   app.DirectorioEditFieldLabel.Visible = 'off';            
                   app.NombreEditField.Visible = 'off';
                   app.NombreEditFieldLabel.Visible = 'off';        
                   app.fileStatus.Visible = 'off';           
                   app.TamaoKBEditField.Visible = 'off';
                   app.TamaoKBEditFieldLabel.Visible = 'off';            
                   app.DuracionEditField.Visible = 'off';
                   app.DuracionEditFieldLabel.Visible = 'off';           
                   app.SelecioneunamuestraButton.Visible = 'off';
                   
                   data = loadMatFile_t(fullfile(path, file));
                    
                case 'Exportar'
                   app.CreadoelEditField.Visible = 'off';
                   app.CreadoelEditFieldLabel.Visible = 'off';
                   app.DirectorioEditField.Visible = 'off';
                   app.DirectorioEditFieldLabel.Visible = 'off';            
                   app.NombreEditField.Visible = 'off';
                   app.NombreEditFieldLabel.Visible = 'off';            
                   app.fileStatus.Visible = 'off';          
                   app.TamaoKBEditField.Visible = 'off';
                   app.TamaoKBEditFieldLabel.Visible = 'off';        
                   app.DuracionEditField.Visible = 'off';
                   app.DuracionEditFieldLabel.Visible = 'off';           
                   app.SelecioneunamuestraButton.Visible = 'off';
                   
                case 'About'
                   app.CreadoelEditField.Visible = 'off';
                   app.CreadoelEditFieldLabel.Visible = 'off';
                   app.DirectorioEditField.Visible = 'off';
                   app.DirectorioEditFieldLabel.Visible = 'off';            
                   app.NombreEditField.Visible = 'off';
                   app.NombreEditFieldLabel.Visible = 'off';            
                   app.fileStatus.Visible = 'off';          
                   app.TamaoKBEditField.Visible = 'off';
                   app.TamaoKBEditFieldLabel.Visible = 'off';        
                   app.DuracionEditField.Visible = 'off';
                   app.DuracionEditFieldLabel.Visible = 'off';           
                   app.SelecioneunamuestraButton.Visible = 'off';
                    
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fileStatus = uilabel(app.UIFigure);
            app.fileStatus.Text = 'Seleccione una muestra .mat';
            app.SelecioneunamuestraButton = uibutton(app.UIFigure, 'push');
            app.SelecioneunamuestraButton.ButtonPushedFcn = createCallbackFcn(app, @SelecioneunamuestraButtonPushed, true);
            app.SelecioneunamuestraButton.Text = {'Selecione una muestra'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Entradas', 'Visualizar', 'Exportar', 'About '};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Entradas';
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = {'Nombre: '; ''};
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.NombreEditField.Editable = 'off';
            app.TamaoKBEditFieldLabel = uilabel(app.UIFigure);
            app.TamaoKBEditFieldLabel.Text = {'Tamaño (KB):'; ''};
            app.TamaoKBEditField = uieditfield(app.UIFigure, 'text');
            app.TamaoKBEditField.Editable = 'off';
            app.CreadoelEditFieldLabel = uilabel(app.UIFigure);
            app.CreadoelEditFieldLabel.Text = {'Creado el:'; ''};
            app.CreadoelEditField = uieditfield(app.UIFigure, 'text');
            app.CreadoelEditField.Editable = 'off';
            app.DuracionEditFieldLabel = uilabel(app.UIFigure);
            app.DuracionEditFieldLabel.Text = 'Duracion:';
            app.DuracionEditField = uieditfield(app.UIFigure, 'text');
            app.DuracionEditField.Editable = 'off';
            app.DirectorioEditFieldLabel = uilabel(app.UIFigure);
            app.DirectorioEditFieldLabel.Text = 'Directorio:';
            app.DirectorioEditField = uieditfield(app.UIFigure, 'text');
            app.DirectorioEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SyS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end